classdef FutureHome < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SmartHome                      matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoaddataMenu                   matlab.ui.container.Menu
        ImportdatasetMenu              matlab.ui.container.Menu
        ParsedataMenu                  matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        Consulta                       matlab.ui.container.Tab
        Button                         matlab.ui.control.Button
        ExperienceLabel                matlab.ui.control.Label
        ExperienciaDropDown            matlab.ui.control.DropDown
        LightingSwitchLabel            matlab.ui.control.Label
        LightingSwitch                 matlab.ui.control.ToggleSwitch
        lamp1                          matlab.ui.control.Button
        lamp2                          matlab.ui.control.Button
        lamp3                          matlab.ui.control.Button
        lamp4                          matlab.ui.control.Button
        lamp5                          matlab.ui.control.Button
        lamp6                          matlab.ui.control.Button
        lamp7                          matlab.ui.control.Button
        lamp8                          matlab.ui.control.Button
        lamp9                          matlab.ui.control.Button
        lamp10                         matlab.ui.control.Button
        lamp11                         matlab.ui.control.Button
        lamp12                         matlab.ui.control.Button
        lamp13                         matlab.ui.control.Button
        lamp14                         matlab.ui.control.Button
        lamp15                         matlab.ui.control.Button
        OutputTextAreaLabel            matlab.ui.control.Label
        OutputTextArea                 matlab.ui.control.TextArea
        PowerPlugSwitchLabel           matlab.ui.control.Label
        PowerPlugSwitch                matlab.ui.control.ToggleSwitch
        plug1                          matlab.ui.control.Button
        plug2                          matlab.ui.control.Button
        plug3                          matlab.ui.control.Button
        plug4                          matlab.ui.control.Button
        plug5                          matlab.ui.control.Button
        plug6                          matlab.ui.control.Button
        plug7                          matlab.ui.control.Button
        plug8                          matlab.ui.control.Button
        plug9                          matlab.ui.control.Button
        plug10                         matlab.ui.control.Button
        plug11                         matlab.ui.control.Button
        plug12                         matlab.ui.control.Button
        ShuttersSwitchLabel            matlab.ui.control.Label
        ShuttersSwitch                 matlab.ui.control.ToggleSwitch
        shut1                          matlab.ui.control.Button
        shut2                          matlab.ui.control.Button
        shut3                          matlab.ui.control.Button
        shut4                          matlab.ui.control.Button
        shut5                          matlab.ui.control.Button
        shut6                          matlab.ui.control.Button
        shut7                          matlab.ui.control.Button
        shut8                          matlab.ui.control.Button
        shut9                          matlab.ui.control.Button
        shut10                         matlab.ui.control.Button
        open1                          matlab.ui.control.Button
        open2                          matlab.ui.control.Button
        open3                          matlab.ui.control.Button
        open4                          matlab.ui.control.Button
        open5                          matlab.ui.control.Button
        open6                          matlab.ui.control.Button
        open7                          matlab.ui.control.Button
        open8                          matlab.ui.control.Button
        open9                          matlab.ui.control.Button
        open10                         matlab.ui.control.Button
        open11                         matlab.ui.control.Button
        open12                         matlab.ui.control.Button
        open13                         matlab.ui.control.Button
        open14                         matlab.ui.control.Button
        open15                         matlab.ui.control.Button
        OpeningSwitchLabel             matlab.ui.control.Label
        OpeningSwitch                  matlab.ui.control.ToggleSwitch
        PresenceSwitchLabel            matlab.ui.control.Label
        PresenceSwitch                 matlab.ui.control.ToggleSwitch
        pres1                          matlab.ui.control.Button
        pres2                          matlab.ui.control.Button
        RGBSwitchLabel                 matlab.ui.control.Label
        RGBSwitch                      matlab.ui.control.ToggleSwitch
        rgb1                           matlab.ui.control.Button
        rgb2                           matlab.ui.control.Button
        rgb3                           matlab.ui.control.Button
        rgb4                           matlab.ui.control.Button
        MultimediaSwitchLabel          matlab.ui.control.Label
        MultimediaSwitch               matlab.ui.control.ToggleSwitch
        mult1                          matlab.ui.control.Button
        mult2                          matlab.ui.control.Button
        mult3                          matlab.ui.control.Button
        mult4                          matlab.ui.control.Button
        STARTButton                    matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        TemperatureGaugeLabel          matlab.ui.control.Label
        TemperatureGauge               matlab.ui.control.Gauge
        HumidityGaugeLabel             matlab.ui.control.Label
        HumidityGauge                  matlab.ui.control.Gauge
        LuminosityGaugeLabel           matlab.ui.control.Label
        LuminosityGauge                matlab.ui.control.Gauge
        VentilationAirSpeedGaugeLabel  matlab.ui.control.Label
        VentilationAirSpeedGauge       matlab.ui.control.Gauge
        SmellGaugeLabel                matlab.ui.control.Label
        SmellGauge                     matlab.ui.control.Gauge
        NoiseLevelGaugeLabel           matlab.ui.control.Label
        NoiseLevelGauge                matlab.ui.control.Gauge
        ThermalComfortGaugeLabel       matlab.ui.control.Label
        ThermalComfortGauge            matlab.ui.control.Gauge
        GlobalComfortGaugeLabel        matlab.ui.control.Label
        GlobalComfortGauge             matlab.ui.control.Gauge
        AirQualityGaugeLabel           matlab.ui.control.Label
        AirQualityGauge                matlab.ui.control.Gauge
        UsersPerceptionsLabel          matlab.ui.control.Label
        UsersFeelingsLabel             matlab.ui.control.Label
        AgreeablenessofBackgroundNoiseGauge_2Label  matlab.ui.control.Label
        AgreeablenessofBackgroundNoiseGauge_2  matlab.ui.control.Gauge
        LightingComfortGaugeLabel      matlab.ui.control.Label
        LightingComfortGauge           matlab.ui.control.Gauge
        AcousticComfortGaugeLabel      matlab.ui.control.Label
        AcousticComfortGauge           matlab.ui.control.Gauge
        ShowValuesSwitch_2Label        matlab.ui.control.Label
        ShowValuesSwitch_2             matlab.ui.control.RockerSwitch
        MiscellaneousSwitchLabel       matlab.ui.control.Label
        MiscellaneousSwitch            matlab.ui.control.ToggleSwitch
        temp                           matlab.ui.control.Button
        temp_2                         matlab.ui.control.Button
        co2                            matlab.ui.control.Button
        humidity                       matlab.ui.control.Button
        DateDropDown                   matlab.ui.control.DropDown
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Teste                          matlab.ui.container.Tab
        Panel_2                        matlab.ui.container.Panel
        TemperatureGauge_2Label        matlab.ui.control.Label
        TemperatureGauge_2             matlab.ui.control.Gauge
        HumidityGauge_2Label           matlab.ui.control.Label
        HumidityGauge_2                matlab.ui.control.Gauge
        LuminosityGauge_2Label         matlab.ui.control.Label
        LuminosityGauge_2              matlab.ui.control.Gauge
        VentilationAirSpeedGauge_2Label  matlab.ui.control.Label
        VentilationAirSpeedGauge_2     matlab.ui.control.Gauge
        SmellGauge_2Label              matlab.ui.control.Label
        SmellGauge_2                   matlab.ui.control.Gauge
        NoiseLevelGauge_2Label         matlab.ui.control.Label
        NoiseLevelGauge_2              matlab.ui.control.Gauge
        ThermalComfortGauge_2Label     matlab.ui.control.Label
        ThermalComfortGauge_2          matlab.ui.control.Gauge
        GlobalComfortGauge_2Label      matlab.ui.control.Label
        GlobalComfortGauge_2           matlab.ui.control.Gauge
        AirQualityGauge_2Label         matlab.ui.control.Label
        AirQualityGauge_2              matlab.ui.control.Gauge
        UsersPerceptionsLabel_2        matlab.ui.control.Label
        UsersFeelingsLabel_2           matlab.ui.control.Label
        AgreeablenessofBackgroundNoiseGauge_3Label  matlab.ui.control.Label
        AgreeablenessofBackgroundNoiseGauge_3  matlab.ui.control.Gauge
        LightingComfortGauge_2Label    matlab.ui.control.Label
        LightingComfortGauge_2         matlab.ui.control.Gauge
        AcousticComfortGauge_2Label    matlab.ui.control.Label
        AcousticComfortGauge_2         matlab.ui.control.Gauge
        DimmedSliderLabel              matlab.ui.control.Label
        DimmedSlider                   matlab.ui.control.Slider
        BinarySwitchLabel              matlab.ui.control.Label
        BinarySwitch                   matlab.ui.control.Switch
        RGBSwitch_2Label               matlab.ui.control.Label
        RGBSwitch_2                    matlab.ui.control.Switch
        ShuttersSwitch_2Label          matlab.ui.control.Label
        ShuttersSwitch_2               matlab.ui.control.RockerSwitch
        SunBlindsDropDownLabel         matlab.ui.control.Label
        SunBlindsDropDown              matlab.ui.control.DropDown
        VentilationSwitchLabel         matlab.ui.control.Label
        VentilationSwitch              matlab.ui.control.RockerSwitch
        TemperatureSliderLabel         matlab.ui.control.Label
        TemperatureSlider              matlab.ui.control.Slider
        HumiditySliderLabel            matlab.ui.control.Label
        HumiditySlider                 matlab.ui.control.Slider
        CO2SliderLabel                 matlab.ui.control.Label
        CO2Slider                      matlab.ui.control.Slider
        NoiseSliderLabel               matlab.ui.control.Label
        NoiseSlider                    matlab.ui.control.Slider
        DetailsTab                     matlab.ui.container.Tab
        ProcessingTextAreaLabel        matlab.ui.control.Label
        ProcessingTextArea             matlab.ui.control.TextArea
    end


    properties (Access = public)
            data=[];
       
    end

    methods (Access = public)
            
        %% Extracts and returns experiments start and end times (Julian)
        % data_source : array with Sensors data
        % exp_idx : experiment column index
        % time_idx : posix colummn index
        % returns [ experiment_id start_time_julian end_time_julian
        % start_time_posix end_time_posix]
        function [out]=getExperimentTimes(data_source,exp_idx,time_idx)
        e = unique( data_source(:,exp_idx));
        exp_times=[];
            for i=1:size(e,1)
                   [row_idx, ~] = find(data_source(:, exp_idx) == e(i));
                    from_mame=data_source(row_idx,:);
                    exp_start = datetime(str2num(char(from_mame(1,time_idx)))/1000,'ConvertFrom', 'posixtime');
                    exp_end = datetime(str2num(char(from_mame(end-1,time_idx)))/1000,'ConvertFrom', 'posixtime');
                    exp_times = [exp_times; e(i) datestr(exp_start) datestr(exp_end) from_mame(1,time_idx) from_mame(end-1,time_idx)];
            end 
        [~,idx]=sort(double(string(exp_times(:,1))));
        out=exp_times(idx,:);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.data=load("domusdata");
            app.STARTButton.Enable = 'on';
            %extrair número das experiências
            a=sortrows(str2double(unique(app.data.Sensors_data(:,2))));
            b={};
             for i=1:size(a,1)
                 b{end+1}=char(num2str(a(i)));
             end
            
            app.ExperienciaDropDown.Items=b;
       
        end

        % Value changed function: LightingSwitch
        function LightingSwitchValueChanged(app, event)
            value = app.LightingSwitch.Value;
            switch value
                    case 'Off'
                        app.lamp1.Visible='off';
                        app.lamp2.Visible='off';
                        app.lamp3.Visible='off';
                        app.lamp4.Visible='off';
                        app.lamp5.Visible='off';
                        app.lamp6.Visible='off';
                        app.lamp7.Visible='off';
                        app.lamp8.Visible='off';
                        app.lamp9.Visible='off';
                        app.lamp10.Visible='off';
                        app.lamp11.Visible='off';
                        app.lamp12.Visible='off';
                        app.lamp13.Visible='off';
                        app.lamp14.Visible='off';
                        app.lamp15.Visible='off';
                    case 'On'
                        app.lamp1.Visible='on';
                        app.lamp2.Visible='on';
                        app.lamp3.Visible='on';
                        app.lamp4.Visible='on';
                        app.lamp5.Visible='on';
                        app.lamp6.Visible='on';
                        app.lamp7.Visible='on';
                        app.lamp8.Visible='on';
                        app.lamp9.Visible='on';
                        app.lamp10.Visible='on';
                        app.lamp11.Visible='on';
                        app.lamp12.Visible='on';
                        app.lamp13.Visible='on';
                        app.lamp14.Visible='on';
                        app.lamp15.Visible='on';
            end


        end

        % Value changed function: PowerPlugSwitch
        function PowerPlugSwitchValueChanged(app, event)
            value = app.PowerPlugSwitch.Value;
            switch value
                    case 'Off'
                        app.plug1.Visible='off';
                        app.plug2.Visible='off';
                        app.plug3.Visible='off';
                        app.plug4.Visible='off';
                        app.plug5.Visible='off';
                        app.plug6.Visible='off';
                        app.plug7.Visible='off';
                        app.plug8.Visible='off';
                        app.plug9.Visible='off';
                        app.plug10.Visible='off';
                        app.plug11.Visible='off';
                        app.plug12.Visible='off';
                    case 'On'
                        app.plug1.Visible='on';
                        app.plug2.Visible='on';
                        app.plug3.Visible='on';
                        app.plug4.Visible='on';
                        app.plug5.Visible='on';
                        app.plug6.Visible='on';
                        app.plug7.Visible='on';
                        app.plug8.Visible='on';
                        app.plug9.Visible='on';
                        app.plug10.Visible='on';
                        app.plug11.Visible='on';
                        app.plug12.Visible='on';
            end
        end

        % Value changed function: ShuttersSwitch
        function ShuttersSwitchValueChanged(app, event)
            value = app.ShuttersSwitch.Value;
            switch value
                    case 'Off'
                        app.shut1.Visible='off';
                        app.shut2.Visible='off';
                        app.shut3.Visible='off';
                        app.shut4.Visible='off';
                        app.shut5.Visible='off';
                        app.shut6.Visible='off';
                        app.shut7.Visible='off';
                        app.shut8.Visible='off';
                        app.shut9.Visible='off';
                        app.shut10.Visible='off';
                    case 'On'
                        app.shut1.Visible='on';
                        app.shut2.Visible='on';
                        app.shut3.Visible='on';
                        app.shut4.Visible='on';
                        app.shut5.Visible='on';
                        app.shut6.Visible='on';
                        app.shut7.Visible='on';
                        app.shut8.Visible='on';
                        app.shut9.Visible='on';
                        app.shut10.Visible='on';
            end
        end

        % Value changed function: OpeningSwitch
        function OpeningSwitchValueChanged(app, event)
            value = app.OpeningSwitch.Value;
            switch value
                    case 'Off'
                        app.open1.Visible='off';
                        app.open2.Visible='off';
                        app.open3.Visible='off';
                        app.open4.Visible='off';
                        app.open5.Visible='off';
                        app.open6.Visible='off';
                        app.open7.Visible='off';
                        app.open8.Visible='off';
                        app.open9.Visible='off';
                        app.open10.Visible='off';
                        app.open11.Visible='off';
                        app.open12.Visible='off';
                        app.open13.Visible='off';
                        app.open14.Visible='off';
                        app.open15.Visible='off';
                    case 'On'
                        app.open1.Visible='on';
                        app.open2.Visible='on';
                        app.open3.Visible='on';
                        app.open4.Visible='on';
                        app.open5.Visible='on';
                        app.open6.Visible='on';
                        app.open7.Visible='on';
                        app.open8.Visible='on';
                        app.open9.Visible='on';
                        app.open10.Visible='on';
                        app.open11.Visible='on';
                        app.open12.Visible='on';
                        app.open13.Visible='on';
                        app.open14.Visible='on';
                        app.open15.Visible='on';
            end
        end

        % Value changed function: PresenceSwitch
        function PresenceSwitchValueChanged(app, event)
            value = app.PresenceSwitch.Value;
            switch value
                    case 'Off'
                        app.pres1.Visible='off';
                        app.pres2.Visible='off';
                        
                    case 'On'
                        app.pres1.Visible='on';
                        app.pres2.Visible='on';
            end
        end

        % Value changed function: RGBSwitch
        function RGBSwitchValueChanged(app, event)
            value = app.RGBSwitch.Value;
            switch value
                    case 'Off'
                        app.rgb1.Visible='off';
                        app.rgb2.Visible='off';
                        app.rgb3.Visible='off';
                        app.rgb4.Visible='off';
                        
                    case 'On'
                        app.rgb1.Visible='on';
                        app.rgb2.Visible='on';
                        app.rgb3.Visible='on';
                        app.rgb4.Visible='on';
            end
        end

        % Value changed function: MultimediaSwitch
        function MultimediaSwitchValueChanged(app, event)
            value = app.MultimediaSwitch.Value;
            switch value
                    case 'Off'
                        app.mult1.Visible='off';
                        app.mult2.Visible='off';
                        app.mult3.Visible='off';
                        app.mult4.Visible='off';
                        
                    case 'On'
                        app.mult1.Visible='on';
                        app.mult2.Visible='on';
                        app.mult3.Visible='on';
                        app.mult4.Visible='on';
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
 
            %app.GlobalComfortGauge.Value=app.data.x;

        end

        % Menu selected function: LoaddataMenu
        function LoaddataMenuSelected(app, event)
            t=uigetfile('*.mat');
            app.data=load(t);
            app.STARTButton.Enable = 'on';
            %extrair número das experiências
            a=sortrows(str2double(unique(app.data.Sensors_data(:,2))));
            b={};
             for i=1:size(a,1)
                 b{end+1}=char(num2str(a(i)));
             end            
            app.ExperienciaDropDown.Items=b;
       
            
            %d=datetime(str2double(Sensors_data(1824,3))/1000, 'ConvertFrom', 'posixtime')            
        end

        % Value changed function: ExperienciaDropDown
        function ExperienciaDropDownValueChanged(app, event)
            value = app.ExperienciaDropDown.Value;   
            [row_idx, ~] = find(app.data.feedback_dump(:, 1) == value);
            info=app.data.feedback_dump(row_idx,:); 
            
    
            [C,ia,ic] = unique(info(:,2),'rows');
            info = info(ia,:); 
%             app.OutputTextArea.Value=""+size(info,1);   
            names1 = datestr(datetime(str2double(info(:,2))/1000, 'ConvertFrom', 'posixtime'));
%             names2 = info(:,4);
            names1=unique(cellstr(names1))
            
%             Names = join(cellstr(cat(2,names1,names2)));
            
            app.DateDropDown.Items=names1;                
        end

        % Value changed function: MiscellaneousSwitch
        function MiscellaneousSwitchValueChanged(app, event)
            value = app.MiscellaneousSwitch.Value;
            switch value
                    case 'Off'
                        app.temp.Visible='off';
                        app.temp_2.Visible='off';
                        app.co2.Visible='off';
                        app.humidity.Visible='off';
                        
                    case 'On'
                        app.temp.Visible='on';
                        app.temp_2.Visible='on';
                        app.co2.Visible='on';
                        app.humidity.Visible='on';
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            I = find(strcmp(app.DateDropDown.Items,app.DateDropDown.Value));
            app.OutputTextArea.Value=""+I; 
            if (I==length(app.DateDropDown.Items)) I=0; end;
            app.DateDropDown.Value=app.DateDropDown.Items{I+1};
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            I = find(strcmp(app.DateDropDown.Items,app.DateDropDown.Value));
            app.OutputTextArea.Value=""+I; 
            if (I==1) I=length(app.DateDropDown.Items)+1; end;
            app.DateDropDown.Value=app.DateDropDown.Items{I-1};
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SmartHome = uifigure;
            app.SmartHome.Name = 'Smart Home';
            app.FileMenu = uimenu(app.SmartHome);
            app.FileMenu.Text = 'File';
            app.LoaddataMenu = uimenu(app.FileMenu);
            app.LoaddataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoaddataMenuSelected, true);
            app.LoaddataMenu.Text = 'Load data';
            app.ImportdatasetMenu = uimenu(app.FileMenu);
            app.ImportdatasetMenu.Text = 'Import dataset';
            app.ParsedataMenu = uimenu(app.FileMenu);
            app.ParsedataMenu.Text = 'Parse data';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.TabGroup = uitabgroup(app.SmartHome);
            app.Consulta = uitab(app.TabGroup);
            app.Consulta.Title = 'Browser';
            app.Button = uibutton(app.Consulta, 'push');
            app.Button.Icon = 'background.png';
            app.Button.Text = '';
            app.ExperienceLabel = uilabel(app.Consulta);
            app.ExperienceLabel.Text = 'Experience';
            app.ExperienciaDropDown = uidropdown(app.Consulta);
            app.ExperienciaDropDown.Items = {};
            app.ExperienciaDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperienciaDropDownValueChanged, true);
            app.ExperienciaDropDown.Value = {};
            app.LightingSwitchLabel = uilabel(app.Consulta);
            app.LightingSwitchLabel.Text = 'Lighting';
            app.LightingSwitch = uiswitch(app.Consulta, 'toggle');
            app.LightingSwitch.ValueChangedFcn = createCallbackFcn(app, @LightingSwitchValueChanged, true);
            app.lamp1 = uibutton(app.Consulta, 'push');
            app.lamp1.Icon = 'Light_bulb.png';
            app.lamp1.Visible = 'off';
            app.lamp1.Text = '';
            app.lamp2 = uibutton(app.Consulta, 'push');
            app.lamp2.Icon = 'Light_bulb.png';
            app.lamp2.Visible = 'off';
            app.lamp2.Text = '';
            app.lamp3 = uibutton(app.Consulta, 'push');
            app.lamp3.Icon = 'Light_bulb.png';
            app.lamp3.Visible = 'off';
            app.lamp3.Text = '';
            app.lamp4 = uibutton(app.Consulta, 'push');
            app.lamp4.Icon = 'Light_bulb.png';
            app.lamp4.Visible = 'off';
            app.lamp4.Text = '';
            app.lamp5 = uibutton(app.Consulta, 'push');
            app.lamp5.Icon = 'Light_bulb.png';
            app.lamp5.Visible = 'off';
            app.lamp5.Text = '';
            app.lamp6 = uibutton(app.Consulta, 'push');
            app.lamp6.Icon = 'Light_bulb.png';
            app.lamp6.Visible = 'off';
            app.lamp6.Text = '';
            app.lamp7 = uibutton(app.Consulta, 'push');
            app.lamp7.Icon = 'Light_bulb.png';
            app.lamp7.Visible = 'off';
            app.lamp7.Text = '';
            app.lamp8 = uibutton(app.Consulta, 'push');
            app.lamp8.Icon = 'Light_bulb.png';
            app.lamp8.Visible = 'off';
            app.lamp8.Text = '';
            app.lamp9 = uibutton(app.Consulta, 'push');
            app.lamp9.Icon = 'Light_bulb.png';
            app.lamp9.Visible = 'off';
            app.lamp9.Text = '';
            app.lamp10 = uibutton(app.Consulta, 'push');
            app.lamp10.Icon = 'Light_bulb.png';
            app.lamp10.Visible = 'off';
            app.lamp10.Text = '';
            app.lamp11 = uibutton(app.Consulta, 'push');
            app.lamp11.Icon = 'Light_bulb.png';
            app.lamp11.Visible = 'off';
            app.lamp11.Text = '';
            app.lamp12 = uibutton(app.Consulta, 'push');
            app.lamp12.Icon = 'Light_bulb.png';
            app.lamp12.Visible = 'off';
            app.lamp12.Text = '';
            app.lamp13 = uibutton(app.Consulta, 'push');
            app.lamp13.Icon = 'Light_bulb.png';
            app.lamp13.Visible = 'off';
            app.lamp13.Text = '';
            app.lamp14 = uibutton(app.Consulta, 'push');
            app.lamp14.Icon = 'Light_bulb.png';
            app.lamp14.Visible = 'off';
            app.lamp14.Text = '';
            app.lamp15 = uibutton(app.Consulta, 'push');
            app.lamp15.Icon = 'Light_bulb.png';
            app.lamp15.Visible = 'off';
            app.lamp15.Text = '';
            app.OutputTextAreaLabel = uilabel(app.Consulta);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.Consulta);
            app.PowerPlugSwitchLabel = uilabel(app.Consulta);
            app.PowerPlugSwitchLabel.Text = 'Power Plug';
            app.PowerPlugSwitch = uiswitch(app.Consulta, 'toggle');
            app.PowerPlugSwitch.ValueChangedFcn = createCallbackFcn(app, @PowerPlugSwitchValueChanged, true);
            app.plug1 = uibutton(app.Consulta, 'push');
            app.plug1.Icon = 'power-plug-icon.png';
            app.plug1.Visible = 'off';
            app.plug1.Text = '';
            app.plug2 = uibutton(app.Consulta, 'push');
            app.plug2.Icon = 'power-plug-icon.png';
            app.plug2.Visible = 'off';
            app.plug2.Text = '';
            app.plug3 = uibutton(app.Consulta, 'push');
            app.plug3.Icon = 'power-plug-icon.png';
            app.plug3.Visible = 'off';
            app.plug3.Text = '';
            app.plug4 = uibutton(app.Consulta, 'push');
            app.plug4.Icon = 'power-plug-icon.png';
            app.plug4.Visible = 'off';
            app.plug4.Text = '';
            app.plug5 = uibutton(app.Consulta, 'push');
            app.plug5.Icon = 'power-plug-icon.png';
            app.plug5.Visible = 'off';
            app.plug5.Text = '';
            app.plug6 = uibutton(app.Consulta, 'push');
            app.plug6.Icon = 'power-plug-icon.png';
            app.plug6.Visible = 'off';
            app.plug6.Text = '';
            app.plug7 = uibutton(app.Consulta, 'push');
            app.plug7.Icon = 'power-plug-icon.png';
            app.plug7.Visible = 'off';
            app.plug7.Text = '';
            app.plug8 = uibutton(app.Consulta, 'push');
            app.plug8.Icon = 'power-plug-icon.png';
            app.plug8.Visible = 'off';
            app.plug8.Text = '';
            app.plug9 = uibutton(app.Consulta, 'push');
            app.plug9.Icon = 'power-plug-icon.png';
            app.plug9.Visible = 'off';
            app.plug9.Text = '';
            app.plug10 = uibutton(app.Consulta, 'push');
            app.plug10.Icon = 'power-plug-icon.png';
            app.plug10.Visible = 'off';
            app.plug10.Text = '';
            app.plug11 = uibutton(app.Consulta, 'push');
            app.plug11.Icon = 'power-plug-icon.png';
            app.plug11.Visible = 'off';
            app.plug11.Text = '';
            app.plug12 = uibutton(app.Consulta, 'push');
            app.plug12.Icon = 'power-plug-icon.png';
            app.plug12.Visible = 'off';
            app.plug12.Text = '';
            app.ShuttersSwitchLabel = uilabel(app.Consulta);
            app.ShuttersSwitchLabel.Text = 'Shutters';
            app.ShuttersSwitch = uiswitch(app.Consulta, 'toggle');
            app.ShuttersSwitch.ValueChangedFcn = createCallbackFcn(app, @ShuttersSwitchValueChanged, true);
            app.shut1 = uibutton(app.Consulta, 'push');
            app.shut1.Icon = 'shutter.png';
            app.shut1.Visible = 'off';
            app.shut1.Text = '';
            app.shut2 = uibutton(app.Consulta, 'push');
            app.shut2.Icon = 'shutter.png';
            app.shut2.Visible = 'off';
            app.shut2.Text = '';
            app.shut3 = uibutton(app.Consulta, 'push');
            app.shut3.Icon = 'shutter.png';
            app.shut3.Visible = 'off';
            app.shut3.Text = '';
            app.shut4 = uibutton(app.Consulta, 'push');
            app.shut4.Icon = 'shutter.png';
            app.shut4.Visible = 'off';
            app.shut4.Text = '';
            app.shut5 = uibutton(app.Consulta, 'push');
            app.shut5.Icon = 'shutter.png';
            app.shut5.Visible = 'off';
            app.shut5.Text = '';
            app.shut6 = uibutton(app.Consulta, 'push');
            app.shut6.Icon = 'shutter.png';
            app.shut6.Visible = 'off';
            app.shut6.Text = '';
            app.shut7 = uibutton(app.Consulta, 'push');
            app.shut7.Icon = 'shutter.png';
            app.shut7.Visible = 'off';
            app.shut7.Text = '';
            app.shut8 = uibutton(app.Consulta, 'push');
            app.shut8.Icon = 'shutter.png';
            app.shut8.Visible = 'off';
            app.shut8.Text = '';
            app.shut9 = uibutton(app.Consulta, 'push');
            app.shut9.Icon = 'shutter.png';
            app.shut9.Visible = 'off';
            app.shut9.Text = '';
            app.shut10 = uibutton(app.Consulta, 'push');
            app.shut10.Icon = 'shutter.png';
            app.shut10.Visible = 'off';
            app.shut10.Text = '';
            app.open1 = uibutton(app.Consulta, 'push');
            app.open1.Icon = 'door_sensor.png';
            app.open1.Visible = 'off';
            app.open1.Text = '';
            app.open2 = uibutton(app.Consulta, 'push');
            app.open2.Icon = 'door_sensor.png';
            app.open2.Visible = 'off';
            app.open2.Text = '';
            app.open3 = uibutton(app.Consulta, 'push');
            app.open3.Icon = 'door_sensor.png';
            app.open3.Visible = 'off';
            app.open3.Text = '';
            app.open4 = uibutton(app.Consulta, 'push');
            app.open4.Icon = 'door_sensor.png';
            app.open4.Visible = 'off';
            app.open4.Text = '';
            app.open5 = uibutton(app.Consulta, 'push');
            app.open5.Icon = 'door_sensor.png';
            app.open5.Visible = 'off';
            app.open5.Text = '';
            app.open6 = uibutton(app.Consulta, 'push');
            app.open6.Icon = 'door_sensor.png';
            app.open6.Visible = 'off';
            app.open6.Text = '';
            app.open7 = uibutton(app.Consulta, 'push');
            app.open7.Icon = 'door_sensor.png';
            app.open7.Visible = 'off';
            app.open7.Text = '';
            app.open8 = uibutton(app.Consulta, 'push');
            app.open8.Icon = 'door_sensor.png';
            app.open8.Visible = 'off';
            app.open8.Text = '';
            app.open9 = uibutton(app.Consulta, 'push');
            app.open9.Icon = 'door_sensor.png';
            app.open9.Visible = 'off';
            app.open9.Text = '';
            app.open10 = uibutton(app.Consulta, 'push');
            app.open10.Icon = 'door_sensor.png';
            app.open10.Visible = 'off';
            app.open10.Text = '';
            app.open11 = uibutton(app.Consulta, 'push');
            app.open11.Icon = 'door_sensor.png';
            app.open11.Visible = 'off';
            app.open11.Text = '';
            app.open12 = uibutton(app.Consulta, 'push');
            app.open12.Icon = 'door_sensor.png';
            app.open12.Visible = 'off';
            app.open12.Text = '';
            app.open13 = uibutton(app.Consulta, 'push');
            app.open13.Icon = 'door_sensor.png';
            app.open13.Visible = 'off';
            app.open13.Text = '';
            app.open14 = uibutton(app.Consulta, 'push');
            app.open14.Icon = 'door_sensor.png';
            app.open14.Visible = 'off';
            app.open14.Text = '';
            app.open15 = uibutton(app.Consulta, 'push');
            app.open15.Icon = 'door_sensor.png';
            app.open15.Visible = 'off';
            app.open15.Text = '';
            app.OpeningSwitchLabel = uilabel(app.Consulta);
            app.OpeningSwitchLabel.Text = 'Opening';
            app.OpeningSwitch = uiswitch(app.Consulta, 'toggle');
            app.OpeningSwitch.ValueChangedFcn = createCallbackFcn(app, @OpeningSwitchValueChanged, true);
            app.PresenceSwitchLabel = uilabel(app.Consulta);
            app.PresenceSwitchLabel.Text = 'Presence';
            app.PresenceSwitch = uiswitch(app.Consulta, 'toggle');
            app.PresenceSwitch.ValueChangedFcn = createCallbackFcn(app, @PresenceSwitchValueChanged, true);
            app.pres1 = uibutton(app.Consulta, 'push');
            app.pres1.Icon = 'presence-detector.png';
            app.pres1.Visible = 'off';
            app.pres1.Text = '';
            app.pres2 = uibutton(app.Consulta, 'push');
            app.pres2.Icon = 'presence-detector.png';
            app.pres2.Visible = 'off';
            app.pres2.Text = '';
            app.RGBSwitchLabel = uilabel(app.Consulta);
            app.RGBSwitchLabel.Text = 'RGB';
            app.RGBSwitch = uiswitch(app.Consulta, 'toggle');
            app.RGBSwitch.ValueChangedFcn = createCallbackFcn(app, @RGBSwitchValueChanged, true);
            app.rgb1 = uibutton(app.Consulta, 'push');
            app.rgb1.Icon = 'rgb.jpg';
            app.rgb1.Visible = 'off';
            app.rgb1.Text = '';
            app.rgb2 = uibutton(app.Consulta, 'push');
            app.rgb2.Icon = 'rgb.jpg';
            app.rgb2.Visible = 'off';
            app.rgb2.Text = '';
            app.rgb3 = uibutton(app.Consulta, 'push');
            app.rgb3.Icon = 'rgb.jpg';
            app.rgb3.Visible = 'off';
            app.rgb3.Text = '';
            app.rgb4 = uibutton(app.Consulta, 'push');
            app.rgb4.Icon = 'rgb-inverse.jpg';
            app.rgb4.Visible = 'off';
            app.rgb4.Text = '';
            app.MultimediaSwitchLabel = uilabel(app.Consulta);
            app.MultimediaSwitchLabel.Text = 'Multimedia';
            app.MultimediaSwitch = uiswitch(app.Consulta, 'toggle');
            app.MultimediaSwitch.ValueChangedFcn = createCallbackFcn(app, @MultimediaSwitchValueChanged, true);
            app.mult1 = uibutton(app.Consulta, 'push');
            app.mult1.Icon = 'audio.jpg';
            app.mult1.Visible = 'off';
            app.mult1.Text = '';
            app.mult2 = uibutton(app.Consulta, 'push');
            app.mult2.Icon = 'audio.jpg';
            app.mult2.Visible = 'off';
            app.mult2.Text = '';
            app.mult3 = uibutton(app.Consulta, 'push');
            app.mult3.Icon = 'audio.jpg';
            app.mult3.Visible = 'off';
            app.mult3.Text = '';
            app.mult4 = uibutton(app.Consulta, 'push');
            app.mult4.Icon = 'tv.png';
            app.mult4.Visible = 'off';
            app.mult4.Text = '';
            app.STARTButton = uibutton(app.Consulta, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Enable = 'off';
            app.STARTButton.Text = 'START';
            app.Panel = uipanel(app.Consulta);
            app.TemperatureGaugeLabel = uilabel(app.Panel);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGauge = uigauge(app.Panel, 'circular');
            app.TemperatureGauge.Limits = [0 10];
            app.HumidityGaugeLabel = uilabel(app.Panel);
            app.HumidityGaugeLabel.Text = 'Humidity';
            app.HumidityGauge = uigauge(app.Panel, 'circular');
            app.HumidityGauge.Limits = [0 10];
            app.LuminosityGaugeLabel = uilabel(app.Panel);
            app.LuminosityGaugeLabel.Text = 'Luminosity';
            app.LuminosityGauge = uigauge(app.Panel, 'circular');
            app.LuminosityGauge.Limits = [0 10];
            app.VentilationAirSpeedGaugeLabel = uilabel(app.Panel);
            app.VentilationAirSpeedGaugeLabel.Text = 'Ventilation / Air Speed';
            app.VentilationAirSpeedGauge = uigauge(app.Panel, 'circular');
            app.VentilationAirSpeedGauge.Limits = [0 10];
            app.SmellGaugeLabel = uilabel(app.Panel);
            app.SmellGaugeLabel.Text = 'Smell';
            app.SmellGauge = uigauge(app.Panel, 'circular');
            app.SmellGauge.Limits = [0 10];
            app.NoiseLevelGaugeLabel = uilabel(app.Panel);
            app.NoiseLevelGaugeLabel.Text = 'Noise Level';
            app.NoiseLevelGauge = uigauge(app.Panel, 'circular');
            app.NoiseLevelGauge.Limits = [0 10];
            app.ThermalComfortGaugeLabel = uilabel(app.Panel);
            app.ThermalComfortGaugeLabel.Text = 'Thermal Comfort';
            app.ThermalComfortGauge = uigauge(app.Panel, 'circular');
            app.ThermalComfortGauge.Limits = [0 10];
            app.GlobalComfortGaugeLabel = uilabel(app.Panel);
            app.GlobalComfortGaugeLabel.Text = 'Global Comfort';
            app.GlobalComfortGauge = uigauge(app.Panel, 'circular');
            app.GlobalComfortGauge.Limits = [0 10];
            app.AirQualityGaugeLabel = uilabel(app.Panel);
            app.AirQualityGaugeLabel.Text = 'Air Quality';
            app.AirQualityGauge = uigauge(app.Panel, 'circular');
            app.AirQualityGauge.Limits = [0 10];
            app.UsersPerceptionsLabel = uilabel(app.Panel);
            app.UsersPerceptionsLabel.Text = 'User''s Perceptions';
            app.UsersFeelingsLabel = uilabel(app.Panel);
            app.UsersFeelingsLabel.Text = 'User''s Feelings';
            app.AgreeablenessofBackgroundNoiseGauge_2Label = uilabel(app.Panel);
            app.AgreeablenessofBackgroundNoiseGauge_2Label.Text = 'Agreeableness of Background Noise';
            app.AgreeablenessofBackgroundNoiseGauge_2 = uigauge(app.Panel, 'circular');
            app.AgreeablenessofBackgroundNoiseGauge_2.Limits = [0 10];
            app.LightingComfortGaugeLabel = uilabel(app.Panel);
            app.LightingComfortGaugeLabel.Text = 'Lighting Comfort';
            app.LightingComfortGauge = uigauge(app.Panel, 'circular');
            app.LightingComfortGauge.Limits = [0 10];
            app.AcousticComfortGaugeLabel = uilabel(app.Panel);
            app.AcousticComfortGaugeLabel.Text = 'Acoustic Comfort';
            app.AcousticComfortGauge = uigauge(app.Panel, 'circular');
            app.AcousticComfortGauge.Limits = [0 10];
            app.ShowValuesSwitch_2Label = uilabel(app.Consulta);
            app.ShowValuesSwitch_2Label.Text = 'Show Values';
            app.ShowValuesSwitch_2 = uiswitch(app.Consulta, 'rocker');
            app.MiscellaneousSwitchLabel = uilabel(app.Consulta);
            app.MiscellaneousSwitchLabel.Text = 'Miscellaneous';
            app.MiscellaneousSwitch = uiswitch(app.Consulta, 'toggle');
            app.MiscellaneousSwitch.ValueChangedFcn = createCallbackFcn(app, @MiscellaneousSwitchValueChanged, true);
            app.temp = uibutton(app.Consulta, 'push');
            app.temp.Icon = 'thermometer.png';
            app.temp.Visible = 'off';
            app.temp.Text = '';
            app.temp_2 = uibutton(app.Consulta, 'push');
            app.temp_2.Icon = 'thermometer.png';
            app.temp_2.Visible = 'off';
            app.temp_2.Text = '';
            app.co2 = uibutton(app.Consulta, 'push');
            app.co2.Icon = 'co2.png';
            app.co2.Visible = 'off';
            app.co2.Text = '';
            app.humidity = uibutton(app.Consulta, 'push');
            app.humidity.Icon = 'humidity.png';
            app.humidity.Visible = 'off';
            app.humidity.Text = '';
            app.DateDropDown = uidropdown(app.Consulta);
            app.DateDropDown.Items = {};
            app.DateDropDown.Value = {};
            app.Button_2 = uibutton(app.Consulta, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.Button_3 = uibutton(app.Consulta, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '<';
            app.Teste = uitab(app.TabGroup);
            app.Teste.Title = 'Test';
            app.Panel_2 = uipanel(app.Teste);
            app.TemperatureGauge_2Label = uilabel(app.Panel_2);
            app.TemperatureGauge_2Label.Text = 'Temperature';
            app.TemperatureGauge_2 = uigauge(app.Panel_2, 'circular');
            app.TemperatureGauge_2.Limits = [0 10];
            app.HumidityGauge_2Label = uilabel(app.Panel_2);
            app.HumidityGauge_2Label.Text = 'Humidity';
            app.HumidityGauge_2 = uigauge(app.Panel_2, 'circular');
            app.HumidityGauge_2.Limits = [0 10];
            app.LuminosityGauge_2Label = uilabel(app.Panel_2);
            app.LuminosityGauge_2Label.Text = 'Luminosity';
            app.LuminosityGauge_2 = uigauge(app.Panel_2, 'circular');
            app.LuminosityGauge_2.Limits = [0 10];
            app.VentilationAirSpeedGauge_2Label = uilabel(app.Panel_2);
            app.VentilationAirSpeedGauge_2Label.Text = 'Ventilation / Air Speed';
            app.VentilationAirSpeedGauge_2 = uigauge(app.Panel_2, 'circular');
            app.VentilationAirSpeedGauge_2.Limits = [0 10];
            app.SmellGauge_2Label = uilabel(app.Panel_2);
            app.SmellGauge_2Label.Text = 'Smell';
            app.SmellGauge_2 = uigauge(app.Panel_2, 'circular');
            app.SmellGauge_2.Limits = [0 10];
            app.NoiseLevelGauge_2Label = uilabel(app.Panel_2);
            app.NoiseLevelGauge_2Label.Text = 'Noise Level';
            app.NoiseLevelGauge_2 = uigauge(app.Panel_2, 'circular');
            app.NoiseLevelGauge_2.Limits = [0 10];
            app.ThermalComfortGauge_2Label = uilabel(app.Panel_2);
            app.ThermalComfortGauge_2Label.Text = 'Thermal Comfort';
            app.ThermalComfortGauge_2 = uigauge(app.Panel_2, 'circular');
            app.ThermalComfortGauge_2.Limits = [0 10];
            app.GlobalComfortGauge_2Label = uilabel(app.Panel_2);
            app.GlobalComfortGauge_2Label.Text = 'Global Comfort';
            app.GlobalComfortGauge_2 = uigauge(app.Panel_2, 'circular');
            app.GlobalComfortGauge_2.Limits = [0 10];
            app.AirQualityGauge_2Label = uilabel(app.Panel_2);
            app.AirQualityGauge_2Label.Text = 'Air Quality';
            app.AirQualityGauge_2 = uigauge(app.Panel_2, 'circular');
            app.AirQualityGauge_2.Limits = [0 10];
            app.UsersPerceptionsLabel_2 = uilabel(app.Panel_2);
            app.UsersPerceptionsLabel_2.Text = 'User''s Perceptions';
            app.UsersFeelingsLabel_2 = uilabel(app.Panel_2);
            app.UsersFeelingsLabel_2.Text = 'User''s Feelings';
            app.AgreeablenessofBackgroundNoiseGauge_3Label = uilabel(app.Panel_2);
            app.AgreeablenessofBackgroundNoiseGauge_3Label.Text = 'Agreeableness of Background Noise';
            app.AgreeablenessofBackgroundNoiseGauge_3 = uigauge(app.Panel_2, 'circular');
            app.AgreeablenessofBackgroundNoiseGauge_3.Limits = [0 10];
            app.LightingComfortGauge_2Label = uilabel(app.Panel_2);
            app.LightingComfortGauge_2Label.Text = 'Lighting Comfort';
            app.LightingComfortGauge_2 = uigauge(app.Panel_2, 'circular');
            app.LightingComfortGauge_2.Limits = [0 10];
            app.AcousticComfortGauge_2Label = uilabel(app.Panel_2);
            app.AcousticComfortGauge_2Label.Text = 'Acoustic Comfort';
            app.AcousticComfortGauge_2 = uigauge(app.Panel_2, 'circular');
            app.AcousticComfortGauge_2.Limits = [0 10];
            app.DimmedSliderLabel = uilabel(app.Teste);
            app.DimmedSliderLabel.Text = 'Dimmed';
            app.DimmedSlider = uislider(app.Teste);
            app.DimmedSlider.Limits = [0 32767];
            app.BinarySwitchLabel = uilabel(app.Teste);
            app.BinarySwitchLabel.Text = 'Binary';
            app.BinarySwitch = uiswitch(app.Teste, 'slider');
            app.RGBSwitch_2Label = uilabel(app.Teste);
            app.RGBSwitch_2Label.Text = 'RGB';
            app.RGBSwitch_2 = uiswitch(app.Teste, 'slider');
            app.ShuttersSwitch_2Label = uilabel(app.Teste);
            app.ShuttersSwitch_2Label.Text = 'Shutters';
            app.ShuttersSwitch_2 = uiswitch(app.Teste, 'rocker');
            app.ShuttersSwitch_2.Items = {'Down', 'Up'};
            app.ShuttersSwitch_2.Value = 'Down';
            app.SunBlindsDropDownLabel = uilabel(app.Teste);
            app.SunBlindsDropDownLabel.Text = 'Sun Blinds';
            app.SunBlindsDropDown = uidropdown(app.Teste);
            app.SunBlindsDropDown.Items = {'Down', 'Up', 'Lamellas closed', 'Lamellas opened'};
            app.SunBlindsDropDown.Value = 'Down';
            app.VentilationSwitchLabel = uilabel(app.Teste);
            app.VentilationSwitchLabel.Text = 'Ventilation';
            app.VentilationSwitch = uiswitch(app.Teste, 'rocker');
            app.VentilationSwitch.Items = {'Low Speed', 'High Speed'};
            app.VentilationSwitch.Value = 'Low Speed';
            app.TemperatureSliderLabel = uilabel(app.Teste);
            app.TemperatureSliderLabel.Text = 'Temperature';
            app.TemperatureSlider = uislider(app.Teste);
            app.TemperatureSlider.Limits = [-20 45];
            app.HumiditySliderLabel = uilabel(app.Teste);
            app.HumiditySliderLabel.Text = 'Humidity';
            app.HumiditySlider = uislider(app.Teste);
            app.CO2SliderLabel = uilabel(app.Teste);
            app.CO2SliderLabel.Text = 'CO2';
            app.CO2Slider = uislider(app.Teste);
            app.CO2Slider.Limits = [0 10000];
            app.NoiseSliderLabel = uilabel(app.Teste);
            app.NoiseSliderLabel.Text = 'Noise';
            app.NoiseSlider = uislider(app.Teste);
            app.NoiseSlider.Limits = [0 32767];
            app.DetailsTab = uitab(app.TabGroup);
            app.DetailsTab.Title = 'Details';
            app.ProcessingTextAreaLabel = uilabel(app.DetailsTab);
            app.ProcessingTextAreaLabel.Text = 'Processing ...';
            app.ProcessingTextArea = uitextarea(app.DetailsTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = FutureHome

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SmartHome)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SmartHome)
        end
    end
end